classdef Positioning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RSSITextArea_F                  matlab.ui.control.TextArea
        RSSITextArea_E                  matlab.ui.control.TextArea
        RSSITextArea_D                  matlab.ui.control.TextArea
        RSSITextArea_C                  matlab.ui.control.TextArea
        RSSITextArea_B                  matlab.ui.control.TextArea
        RSSITextArea_A                  matlab.ui.control.TextArea
        RSSITextAreaLabel               matlab.ui.control.Label
        MinusViewCheckBox               matlab.ui.control.CheckBox
        LogpositionsanddistancesCheckBox  matlab.ui.control.CheckBox
        GLabel                          matlab.ui.control.Label
        FLabel                          matlab.ui.control.Label
        ELabel                          matlab.ui.control.Label
        Z_PosTextArea_3                 matlab.ui.control.TextArea
        Z_PosTextArea_3Label            matlab.ui.control.Label
        Y_PosTextArea_3                 matlab.ui.control.TextArea
        Y_PosTextArea_3Label            matlab.ui.control.Label
        X_PosTextArea_3                 matlab.ui.control.TextArea
        X_PosTextArea_3Label            matlab.ui.control.Label
        Z_PosTextArea_2                 matlab.ui.control.TextArea
        Z_PosTextArea_2Label            matlab.ui.control.Label
        Y_PosTextArea_2                 matlab.ui.control.TextArea
        Y_PosTextArea_2Label            matlab.ui.control.Label
        X_PosTextArea_2                 matlab.ui.control.TextArea
        X_PosTextArea_2Label            matlab.ui.control.Label
        MeanpositionacquisitiontimemsTextArea  matlab.ui.control.TextArea
        MeanpositionacquisitiontimemsTextAreaLabel  matlab.ui.control.Label
        PositionacquisitiontimemsTextArea  matlab.ui.control.TextArea
        PositionacquisitiontimemsTextAreaLabel  matlab.ui.control.Label
        SavesettingsastxtButton         matlab.ui.control.Button
        LoadsettingsButton              matlab.ui.control.Button
        ChoosefilewithsettingsTextArea  matlab.ui.control.TextArea
        ChoosefilewithsettingsTextAreaLabel  matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        ZEditFieldLabel_2               matlab.ui.control.Label
        ZEditField_7                    matlab.ui.control.NumericEditField
        ZEditField_6                    matlab.ui.control.NumericEditField
        ZEditField_5                    matlab.ui.control.NumericEditField
        ZEditField_4                    matlab.ui.control.NumericEditField
        ZEditField_3                    matlab.ui.control.NumericEditField
        ZEditField_2                    matlab.ui.control.NumericEditField
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        Z_PosTextArea                   matlab.ui.control.TextArea
        Z_PosTextAreaLabel              matlab.ui.control.Label
        Choose3Dor2DpositioningLabel    matlab.ui.control.Label
        Dor2DPositioningButtonGroup     matlab.ui.container.ButtonGroup
        POS_3D                          matlab.ui.control.RadioButton
        POS_2D                          matlab.ui.control.RadioButton
        CurrentPositionLabel            matlab.ui.control.Label
        Y_PosTextArea                   matlab.ui.control.TextArea
        Y_PosTextAreaLabel              matlab.ui.control.Label
        X_PosTextArea                   matlab.ui.control.TextArea
        X_PosTextAreaLabel              matlab.ui.control.Label
        ChooseserialportLabel           matlab.ui.control.Label
        SerialportDropDown              matlab.ui.control.DropDown
        SerialportDropDownLabel         matlab.ui.control.Label
        StopPositioningButton           matlab.ui.control.Button
        StartPositioningButton          matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        D2DPositioningSystemLabel       matlab.ui.control.Label
        YEditFieldLabel_2               matlab.ui.control.Label
        XEditFieldLabel_2               matlab.ui.control.Label
        PlotAnchorsButton               matlab.ui.control.Button
        Max10x10x10mLabel               matlab.ui.control.Label
        MinMax36Label                   matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        YEditField_7                    matlab.ui.control.NumericEditField
        XEditField_7                    matlab.ui.control.NumericEditField
        FLabel_2                        matlab.ui.control.Label
        YEditField_6                    matlab.ui.control.NumericEditField
        XEditField_6                    matlab.ui.control.NumericEditField
        ELabel_2                        matlab.ui.control.Label
        YEditField_5                    matlab.ui.control.NumericEditField
        XEditField_5                    matlab.ui.control.NumericEditField
        DLabel                          matlab.ui.control.Label
        YEditField_4                    matlab.ui.control.NumericEditField
        XEditField_4                    matlab.ui.control.NumericEditField
        CLabel                          matlab.ui.control.Label
        YEditField_3                    matlab.ui.control.NumericEditField
        XEditField_3                    matlab.ui.control.NumericEditField
        BLabel                          matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        XEditField_2                    matlab.ui.control.NumericEditField
        InformationsTextArea            matlab.ui.control.TextArea
        InformationsTextAreaLabel       matlab.ui.control.Label
        RoomsizeLabel                   matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        EnterpositionsofanchorsLabel    matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        EnternumberofanchorsEditField   matlab.ui.control.NumericEditField
        EnternumberofanchorsEditFieldLabel  matlab.ui.control.Label
        ApplysettingsButton             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % is 3D positioning?
        positioning3D = 0;
        % list of indicators on XY fields anchors
        listOfXFiled = []; % X
        listOfYFiled = []; % Y
        listOfZFiled = []; % Z is like height of room
        listOfRSSIFiled = []; % RSSI
        listOfLetters = ["A", "B", "C", "D", "E", "F"];
        dx = 0.02;
        % here are limits of number of anchors and room size
        maxNumAnchors = 6;
        minNumAnchors = 3;
        maxRoomSize = 20;
        minRoomSize = 1;
        % here are default values of number of anchors and room size
        defRoomWidth = 3;
        defRoomHeight = 5;
        defRoomDepth = 4;
        defNumAnchors = 3;
        % here are default values of anchors coord
        defFirstAnX = 2.8;
        defFirstAnY = 2.5;
        defFirstAnZ = 1.5;
        defSecondAnX = 0.2;
        defSecondAnY = 1.7;
        defSecondAnZ = 1.5;
        defThirdAnX = 0.2;
        defThirdAnY = 3.4;
        defThirdAnZ = 1.5;
        % limit of minimal pos cord
        minCord = -20.00;
        % variables where user input is stored
        numberOfAnchors = 0
        width = 0;
        height = 0;
        depth = 0;
        % aray for coord of anchors
        PX = [];
        PY = [];
        PZ = [];
        P = [];
        % In positioning state
        positioningState = 0;
        % free serialports
        freeports;
        % choosed serial port
        port;
        % device connection UART
        dwm3000Device;
        % distances from UART
        distances;
        % rssi form UART
        rssi;
        % position of an object from trilateration
        objectPos;
        objectPos_2;
        % handle object position on chart, E,F,G are initiators
        objPosition3D_E;
        text3D_E;
        objPosition3D_F;
        text3D_F;
        objPosition3D_G;
        text3D_G;
        objPosition2D_E;
        text2D_E;
        objPosition2D_F;
        text2D_F;
        objPosition2D_G;
        text2D_G;
        X_E = [];
        Y_E = [];
        i_E = 1;
        % handle data to txt file
        txt_position_E = strings();
        txt_position_F = strings();
        txt_position_G = strings();
        txt_distances_E = strings();
        txt_distances_F = strings();
        txt_distances_G = strings();
        % handle txt files
        txt_hDist_E
        txt_hPosition_E
        txt_hDist_F
        txt_hPosition_F
        txt_hDist_G
        txt_hPosition_G
        % array of coord of object for position test
        obj_X = [1.50, 1.55, 1.70, 1.78, 1.90, 2.10, 1.90, 1.78, 1.70, 1.55];
        obj_Y = [2.40, 2.20, 2.50, 2.68, 2.81, 2.91, 2.81, 2.68, 2.50, 2.20];
        obj_Z = [0.7,  0.9,  1.1,  1.5,  1.2,  1.5,  1.7,  1.9,  2.0,  2.20];
        % handle of data file.txt
        appData;
        % array with times to calculate mean
        timeArrivalArray = [];
    end
    
    methods (Access = private)
        
        function result = Get_Position(app)
            % take distances form UART
            try
                [app.distances, app.rssi, error, tag, readDistAndRssi] = Get_Distance_UART_4_RSSI(app.dwm3000Device);
            catch
                error = 1;
            end
            if (error == 0)
                app.InformationsTextArea.Value = "Rozpoczęto pozycjonowanie!";
                % check if 3D positioning is enable
                if app.positioning3D == 1
                    % take object position form trilateration algorithm
%                     app.objectPos = GetPosition_2(app.distances, app.P);
                    try
                        [app.objectPos,app.objectPos_2] = GetPosition(app.distances, app.P);
                    catch
                        app.InformationsTextArea.Value = "Problem z GetPosition";
                    end
                    switch (tag)
                        case "E"
                              if(~isempty(app.objPosition3D_E))
                                  delete(app.objPosition3D_E);
                                  delete(app.text3D_E);
                              end
                              X = round(app.objectPos(1),2);
                              Y = round(app.objectPos(2),2);
                              Z = round(app.objectPos(3),2);
                              app.objPosition3D_E = plot3(app.UIAxes, X, Y, Z,'.r', 'MarkerSize', 35);
                              app.text3D_E = text(app.UIAxes, X + app.dx + 0.02, Y, Z, "E", "FontSize", 15);
                              if (app.LogpositionsanddistancesCheckBox.Value)
                                  time_E = clock();
                                  dataPos_E = num2str(X) + ":" + num2str(Y) + ":" + num2str(Z) + "/" + num2str(time_E(4)) + ":" + num2str(time_E(5)) + ":" + num2str(time_E(6));
                                  dataDist_E = readDistAndRssi + "/" + num2str(time_E(4)) + ":" + num2str(time_E(5)) + ":" + num2str(time_E(6));
                                  app.txt_position_E(end + 1) = dataPos_E;
                                  app.txt_distances_E(end + 1) = dataDist_E;
                              end
                              app.X_PosTextArea.Value = num2str(X);
                              app.Y_PosTextArea.Value = num2str(Y);
                              app.Z_PosTextArea.Value = num2str(Z);
                              for i=1:app.numberOfAnchors
                                  app.listOfRSSIFiled(i).Value = num2str(app.rssi(i));
                              end
                        case "F"
                              if(~isempty(app.objPosition3D_F))
                                  delete(app.objPosition3D_F);
                                  delete(app.text3D_F);
                              end
                              X = round(app.objectPos(1),2);
                              Y = round(app.objectPos(2),2);
                              Z = round(app.objectPos(3),2);
                              app.objPosition3D_F = plot3(app.UIAxes, X, Y, Z,'.b', 'MarkerSize', 35);
                              app.text3D_F = text(app.UIAxes, X + app.dx + 0.02, Y, Z, "F", "FontSize", 15);
                              if (app.LogpositionsanddistancesCheckBox.Value)
                                  time_F = clock();
                                  dataPos_F = num2str(X) + ":" + num2str(Y) + ":" + num2str(Z) + "/" + num2str(time_F(4)) + ":" + num2str(time_F(5)) + ":" + num2str(time_F(6));
                                  dataDist_F = readDistAndRssi + "/" + num2str(time_F(4)) + ":" + num2str(time_F(5)) + ":" + num2str(time_F(6));
                                  app.txt_position_F(end + 1) = dataPos_F;
                                  app.txt_distances_F(end + 1) = dataDist_F;
                              end
                              app.X_PosTextArea_2.Value = num2str(X);
                              app.Y_PosTextArea_2.Value = num2str(Y);
                              app.Z_PosTextArea_2.Value = num2str(Z);
                              for i=1:app.numberOfAnchors
                                  app.listOfRSSIFiled(i).Value = num2str(app.rssi(i));
                              end
                         case "G"
                              if(~isempty(app.objPosition3D_G))
                                  delete(app.objPosition3D_G);
                                  delete(app.text3D_G);
                              end
                              X = round(app.objectPos(1),2);
                              Y = round(app.objectPos(2),2);
                              Z = round(app.objectPos(3),2);
                              app.objPosition3D_G = plot3(app.UIAxes, X, Y, Z,'.k', 'MarkerSize', 35);
                              app.text3D_G = text(app.UIAxes, X + app.dx + 0.02, Y, Z, "G", "FontSize", 15);
                              if (app.LogpositionsanddistancesCheckBox.Value)
                                  time_G = clock();
                                  dataPos_G = num2str(X) + ":" + num2str(Y) + ":" + num2str(Z) + "/" + num2str(time_G(4)) + ":" + num2str(time_G(5)) + ":" + num2str(time_G(6));
                                  dataDist_G = readDistAndRssi + "/" + num2str(time_G(4)) + ":" + num2str(time_G(5)) + ":" + num2str(time_G(6));
                                  app.txt_position_G(end + 1) = dataPos_G;
                                  app.txt_distances_G(end + 1) = dataDist_G;
                              end
                              app.X_PosTextArea_3.Value = num2str(X);
                              app.Y_PosTextArea_3.Value = num2str(Y);
                              app.Z_PosTextArea_3.Value = num2str(Z);
                              for i=1:app.numberOfAnchors
                                  app.listOfRSSIFiled(i).Value = num2str(app.rssi(i));
                              end
                        otherwise
                            %do nothing
                    end %end switch
                else 
                    % take object position form trilateration algorithm
                    [app.objectPos,app.objectPos_2] = GetPosition(app.distances, app.P);
                    switch (tag)
                        case "E"
                              if(~isempty(app.objPosition2D_E))
                                  delete(app.objPosition2D_E);
                                  delete(app.text2D_E);
                              end
                              X = round(app.objectPos(1),2);
                              Y = round(app.objectPos(2),2);
                              Z = 0;
                              app.objPosition2D_E = plot(app.UIAxes, X, Y, '.r', 'MarkerSize', 35);
                              app.text2D_E = text(app.UIAxes, X + app.dx + 0.02, Y, "E", "FontSize", 15);
                              if (app.LogpositionsanddistancesCheckBox.Value)
                                  time_E = clock();
                                  dataPos_E = num2str(X) + ":" + num2str(Y) + ":" + num2str(Z) + "/" + num2str(time_E(4)) + ":" + num2str(time_E(5)) + ":" + num2str(time_E(6));
                                  dataDist_E = readDistAndRssi + "/" + num2str(time_E(4)) + ":" + num2str(time_E(5)) + ":" + num2str(time_E(6));
                                  app.txt_position_E(end + 1) = dataPos_E;
                                  app.txt_distances_E(end + 1) = dataDist_E;
                              end
                              app.X_PosTextArea.Value = num2str(X);
                              app.Y_PosTextArea.Value = num2str(Y);
                              app.Z_PosTextArea.Value = num2str(Z);
                              for i=1:app.numberOfAnchors
                                  app.listOfRSSIFiled(i).Value = num2str(app.rssi(i));
                              end
                        case "F"
                              if(~isempty(app.objPosition2D_F))
                                  delete(app.objPosition2D_F)
                                  delete(app.text2D_F);
                              end
                              X = round(app.objectPos(1),2);
                              Y = round(app.objectPos(2),2);
                              Z = 0;
                              app.objPosition2D_F = plot(app.UIAxes, X, Y, '.b', 'MarkerSize', 35);
                              app.text2D_F = text(app.UIAxes, X + app.dx + 0.02, Y, "F", "FontSize", 15);
                              if (app.LogpositionsanddistancesCheckBox.Value)
                                  time_F = clock();
                                  dataPos_F = num2str(X) + ":" + num2str(Y) + ":" + num2str(Z) + "/" + num2str(time_F(4)) + ":" + num2str(time_F(5)) + ":" + num2str(time_F(6));
                                  dataDist_F = readDistAndRssi + "/" + num2str(time_F(4)) + ":" + num2str(time_F(5)) + ":" + num2str(time_F(6));
                                  app.txt_position_F(end + 1) = dataPos_F;
                                  app.txt_distances_F(end + 1) = dataDist_F;
                              end
                              app.X_PosTextArea_2.Value = num2str(X);
                              app.Y_PosTextArea_2.Value = num2str(Y);
                              app.Z_PosTextArea_2.Value = num2str(Z);
                              for i=1:app.numberOfAnchors
                                  app.listOfRSSIFiled(i).Value = num2str(app.rssi(i));
                              end
                       case "G"
                              if(~isempty(app.objPosition2D_G))
                                  delete(app.objPosition2D_G)
                                   delete(app.text2D_G);
                              end
                              X = round(app.objectPos(1),2);
                              Y = round(app.objectPos(2),2);
                              Z = 0;
                              app.objPosition2D_G = plot(app.UIAxes, X, Y, '.k', 'MarkerSize', 35);
                              app.text2D_G = text(app.UIAxes, X + app.dx + 0.02, Y, "G", "FontSize", 15);
                              if (app.LogpositionsanddistancesCheckBox.Value)
                                  time_G = clock();
                                  dataPos_G = num2str(X) + ":" + num2str(Y) + ":" + num2str(Z) + "/" + num2str(time_G(4)) + ":" + num2str(time_G(5)) + ":" + num2str(time_G(6));
                                  dataDist_G = readDistAndRssi + "/" + num2str(time_G(4)) + ":" + num2str(time_G(5)) + ":" + num2str(time_G(6));
                                  app.txt_position_G(end + 1) = dataPos_G;
                                  app.txt_distances_G(end + 1) = dataDist_G;
                              end
                              app.X_PosTextArea_3.Value = num2str(X);
                              app.Y_PosTextArea_3.Value = num2str(Y);
                              app.Z_PosTextArea_3.Value = num2str(Z);
                              for i=1:app.numberOfAnchors
                                  app.listOfRSSIFiled(i).Value = num2str(app.rssi(i));
                              end
                        otherwise
                            % do nothing
                    end % end switch
                end %end if
            else
                app.InformationsTextArea.Value = "Problem z czytaniem UART, sprawdź wybrany port!/ Sprawdź czy wszystkie anchory/tagi mają zasilanie!";
            end %end if

% Test działa bardzo dobrze
%                 if app.positioning3D == 1
%                     for i=1:length(app.obj_X)
%                         if(~isempty(app.objPosition3D))
%                             delete(app.objPosition3D)
%                         end
%                         app.objPosition3D = plot3(app.UIAxes, app.obj_X(i), app.obj_Y(i), app.obj_Z(i),'.r', 'MarkerSize', 35);
%                         app.X_PosTextArea.Value = num2str(app.obj_X(i));
%                         app.Y_PosTextArea.Value = num2str(app.obj_Y(i));
%                         app.Z_PosTextArea.Value = num2str(app.obj_Z(i));
%                         pause(0.05);
%                     end
%                 else
%                     for i=1:length(app.obj_X)
%                         if(~isempty(app.objPosition2D))
%                             delete(app.objPosition2D)
%                         end
%                         app.objPosition2D = plot(app.UIAxes, app.obj_X(i), app.obj_Y(i), '.r', 'MarkerSize', 35);
%                         app.X_PosTextArea.Value = num2str(app.obj_X(i));
%                         app.Y_PosTextArea.Value = num2str(app.obj_Y(i));
%                         app.Z_PosTextArea.Value = "0";
%                         pause(0.05);
%                     end
%                 end
                   
        end %function Get_Position
        
        % This function disable a XYFileds anchors for range_1 to range_2 
        function results = disableXYFields(app, range_1, range_2)
            for i=range_1:1:range_2
                app.listOfXFiled(i).Enable = "Off";
                app.listOfXFiled(i).Editable = "Off";
                app.listOfYFiled(i).Enable = "Off";
                app.listOfYFiled(i).Editable = "Off";
                app.listOfZFiled(i).Enable = "Off";
                app.listOfZFiled(i).Editable = "Off";
                app.listOfRSSIFiled(i).Enable = "Off";
                app.listOfRSSIFiled(i).Editable = "Off";
            end
        end
        
        function results = enableXYFields(app,range_1, range_2)
            for i=range_1:1:range_2
                app.listOfXFiled(i).Enable = "On";
                app.listOfXFiled(i).Editable = "On";
                app.listOfYFiled(i).Enable = "On";
                app.listOfYFiled(i).Editable = "On";
                app.listOfZFiled(i).Enable = "On";
                app.listOfZFiled(i).Editable = "On";
                app.listOfRSSIFiled(i).Enable = "On"; 
                app.listOfRSSIFiled(i).Editable = "On";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % add XYfileds  for anchors to list
            app.listOfXFiled = [app.XEditField ,app.XEditField_2, app.XEditField_3, app.XEditField_4, app.XEditField_5, app.XEditField_6];
            app.listOfYFiled = [app.YEditField ,app.YEditField_2, app.YEditField_3, app.YEditField_4, app.YEditField_5, app.YEditField_6];
            app.listOfZFiled = [app.ZEditField ,app.ZEditField_2, app.ZEditField_3, app.ZEditField_4, app.ZEditField_5, app.ZEditField_6];
            app.listOfRSSIFiled = [app.RSSITextArea_A ,app.RSSITextArea_B, app.RSSITextArea_C, app.RSSITextArea_D, app.RSSITextArea_E, app.RSSITextArea_F];

            % disable fields with anchors coord
            disableXYFields(app, 4, 6);

            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
            % Set default limits for room size and number of anchors
            app.XEditField_7.Limits = [app.minRoomSize,app.maxRoomSize];
            app.YEditField_7.Limits = [app.minRoomSize,app.maxRoomSize];
            app.ZEditField_7.Limits = [app.minRoomSize,app.maxRoomSize];
            app.EnternumberofanchorsEditField.Limits = [app.minNumAnchors,app.maxNumAnchors];
                
            % default values of room size and number of anchors
            app.XEditField_7.Value = app.defRoomWidth;
            app.YEditField_7.Value = app.defRoomHeight;
            app.ZEditField_7.Value = app.defRoomDepth;
            app.EnternumberofanchorsEditField.Value = app.defNumAnchors;
            app.UIAxes.XTick = (0:0.1:app.defRoomWidth);
            app.UIAxes.YTick = (0:0.1:app.defRoomHeight);
            app.UIAxes.XTickLabel = (0:0.1:app.defRoomWidth);
            app.UIAxes.YTickLabel = (0:0.1:app.defRoomHeight);
            app.UIAxes.XLim = [0,app.defRoomWidth];
            app.UIAxes.YLim = [0,app.defRoomHeight];
            % 3D
            app.UIAxes.ZTick = (0:0.1:app.defRoomDepth);
            app.UIAxes.ZTickLabel = (0:0.1:app.defRoomDepth);
            app.UIAxes.ZLim = [0,app.defRoomDepth];
           
            % default coords of anchors and plot them
            app.XEditField.Value = app.defFirstAnX;
            app.YEditField.Value = app.defFirstAnY;
            app.ZEditField.Value = app.defFirstAnZ;
            if (app.positioning3D == 1)
                plot3(app.UIAxes, app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value,'.g', 'MarkerSize', 20);
                text(app.UIAxes, app.XEditField.Value + app.dx, app.YEditField.Value, app.ZEditField.Value, "A", "FontSize", 15);
            else
                plot(app.UIAxes, app.XEditField.Value, app.YEditField.Value, '.g', 'MarkerSize', 20);
                text(app.UIAxes, app.XEditField.Value + app.dx, app.YEditField.Value,"A", "FontSize", 15);
            end
            hold(app.UIAxes,'on');

            app.XEditField_2.Value = app.defSecondAnX;
            app.YEditField_2.Value = app.defSecondAnY;
            app.ZEditField_2.Value = app.defSecondAnZ;
            if (app.positioning3D == 1)
                plot3(app.UIAxes, app.XEditField_2.Value, app.YEditField_2.Value, app.ZEditField_2.Value,'.g', 'MarkerSize', 20);
                text(app.UIAxes, app.XEditField_2.Value + app.dx, app.YEditField_2.Value, app.ZEditField_2.Value, "B", "FontSize", 15);
            else
                plot(app.UIAxes, app.XEditField_2.Value, app.YEditField_2.Value, '.g', 'MarkerSize', 20);
                text(app.UIAxes, app.XEditField_2.Value + app.dx, app.YEditField_2.Value,"B", "FontSize", 15);
            end
            app.XEditField_3.Value = app.defThirdAnX;
            app.YEditField_3.Value = app.defThirdAnY;
            app.ZEditField_3.Value = app.defThirdAnZ;
            if (app.positioning3D == 1)
                plot3(app.UIAxes, app.XEditField_3.Value, app.YEditField_3.Value, app.ZEditField_3.Value,'.g', 'MarkerSize', 20);
                text(app.UIAxes, app.XEditField_3.Value + app.dx, app.YEditField_3.Value, app.ZEditField_3.Value, "C", "FontSize", 15);
            else
                plot(app.UIAxes, app.XEditField_3.Value, app.YEditField_3.Value, '.g', 'MarkerSize', 20);
                text(app.UIAxes, app.XEditField_3.Value + app.dx, app.YEditField_3.Value,"C", "FontSize", 15);
            end
            
            % limits of coord for anchors
            app.XEditField.Limits = [app.minCord,app.defRoomWidth - app.minCord];
            app.YEditField.Limits = [app.minCord,app.defRoomHeight - app.minCord];
            app.ZEditField.Limits = [app.minCord,app.defRoomDepth - app.minCord];

            app.XEditField_2.Limits = [app.minCord,app.defRoomWidth - app.minCord];
            app.YEditField_2.Limits = [app.minCord,app.defRoomHeight - app.minCord];
            app.ZEditField_2.Limits = [app.minCord,app.defRoomDepth - app.minCord];

            app.XEditField_3.Limits = [app.minCord,app.defRoomWidth - app.minCord];
            app.YEditField_3.Limits = [app.minCord,app.defRoomHeight - app.minCord];
            app.ZEditField_3.Limits = [app.minCord,app.defRoomDepth - app.minCord];

            app.XEditField_4.Limits = [app.minCord,app.defRoomWidth - app.minCord];
            app.YEditField_4.Limits = [app.minCord,app.defRoomHeight - app.minCord];
            app.ZEditField_4.Limits = [app.minCord,app.defRoomDepth - app.minCord];

            app.XEditField_5.Limits = [app.minCord,app.defRoomWidth - app.minCord];
            app.YEditField_5.Limits = [app.minCord,app.defRoomHeight - app.minCord];
            app.ZEditField_5.Limits = [app.minCord,app.defRoomDepth - app.minCord];

            app.XEditField_6.Limits = [app.minCord,app.defRoomWidth - app.minCord];
            app.YEditField_6.Limits = [app.minCord,app.defRoomHeight - app.minCord];
            app.ZEditField_6.Limits = [app.minCord,app.defRoomDepth - app.minCord];

            % take free serial ports
            app.freeports = serialportlist("available");
            app.SerialportDropDown.Items = app.freeports;
            % font size of informations
            app.InformationsTextArea.FontSize = 15;
            % on strat up on start button, off stop button
            app.StartPositioningButton.Enable = "On";
            app.StopPositioningButton.Enable = "Off";
        end

        % Button pushed function: ApplysettingsButton
        function ApplysettingsButtonPushed(app, event)
            % take number of anchors
            app.numberOfAnchors = app.EnternumberofanchorsEditField.Value;  
            % take room size
            app.width = app.XEditField_7.Value;
            app.height = app.YEditField_7.Value;
            app.depth = app.ZEditField_7.Value;
            % clear UIAxes and plot anchors again
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            for i=1:app.numberOfAnchors
                app.PX(i) = app.listOfXFiled(i).Value;
                app.PY(i) = app.listOfYFiled(i).Value;
                if (app.positioning3D == 1)
                    app.PZ(i) = app.listOfZFiled(i).Value;
                    plot3(app.UIAxes, app.PX(i), app.PY(i), app.PZ(i),'.g', 'MarkerSize', 20);
                    text(app.UIAxes, app.PX(i) + app.dx, app.PY(i), app.PZ(i), app.listOfLetters(i), "FontSize", 15);
                    app.P(3,i) = app.PZ(i);
                else
                    plot(app.UIAxes, app.PX(i), app.PY(i), '.g', 'MarkerSize', 20);
                    text(app.UIAxes, app.PX(i) + app.dx, app.PY(i), app.listOfLetters(i), "FontSize", 15);
                    app.P(3,i) = 0.0;
                end
                
                app.P(1,i) = app.PX(i);
                app.P(2,i) = app.PY(i);
                
%                 P_temp(1) = app.PX(i);
%                 P_temp(2) = app.PY(i);
%                 P_temp(3) = 0.0;
%                 app.P(i+(2*(i-1)):3*i) = P_temp';
            end
            app.InformationsTextArea.Value = "Pobrano wprowadzone dane.";
%             app.InformationsTextArea.Value = num2str(app.P(4));
            % Set Start and stop positioning enable
            app.StartPositioningButton.Enable = "On";
            % take choosed serial port
            app.port = app.SerialportDropDown.Value;
        end

        % Value changed function: EnternumberofanchorsEditField
        function EnternumberofanchorsEditFieldValueChanged(app, event)
            numOfAnchors = app.EnternumberofanchorsEditField.Value;
            % Enable number of fields
            enableXYFields(app,1, numOfAnchors);
            % Disable rest of fields
            if (numOfAnchors < app.maxNumAnchors)
                disableXYFields(app, numOfAnchors+1, app.maxNumAnchors);
            end
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
        end

        % Value changed function: XEditField_7
        function XEditField_7ValueChanged(app, event)
                RoomWidth = app.XEditField_7.Value;
                app.XEditField.Limits = [app.minCord,RoomWidth];
                app.XEditField_2.Limits = [app.minCord,RoomWidth];
                app.XEditField_3.Limits = [app.minCord,RoomWidth];
                app.XEditField_4.Limits = [app.minCord,RoomWidth];
                app.XEditField_5.Limits = [app.minCord,RoomWidth];
                app.XEditField_6.Limits = [app.minCord,RoomWidth];
                
                if (app.MinusViewCheckBox.Value)
                    app.UIAxes.XTick = (-RoomWidth:0.1:RoomWidth);
                    app.UIAxes.XTickLabel = (-RoomWidth:0.1:RoomWidth);
                    app.UIAxes.XLim = [-RoomWidth,RoomWidth];
                else
                    app.UIAxes.XTick = (0:0.1:RoomWidth);
                    app.UIAxes.XTickLabel = (0:0.1:RoomWidth);
                    app.UIAxes.XLim = [0,RoomWidth];
                end
                %disable start and stop positionign buttons
                app.StartPositioningButton.Enable = "Off";
                app.StopPositioningButton.Enable = "Off";
        end

        % Value changed function: YEditField_7
        function YEditField_7ValueChanged(app, event)
                %disable start and stop positionign buttons
                app.StartPositioningButton.Enable = "Off";
                app.StopPositioningButton.Enable = "Off";
                RoomHeight = app.YEditField_7.Value;
                app.YEditField.Limits = [app.minCord,RoomHeight - app.minCord];
                app.YEditField_2.Limits = [app.minCord,RoomHeight - app.minCord];
                app.YEditField_3.Limits = [app.minCord,RoomHeight - app.minCord];
                app.YEditField_4.Limits = [app.minCord,RoomHeight - app.minCord];
                app.YEditField_5.Limits = [app.minCord,RoomHeight - app.minCord];
                app.YEditField_6.Limits = [app.minCord,RoomHeight - app.minCord];
                
                if (app.MinusViewCheckBox.Value)
                    app.UIAxes.YTick = (-RoomHeight:0.1:RoomHeight);
                    app.UIAxes.YTickLabel = (-RoomHeight:0.1:RoomHeight);
                    app.UIAxes.YLim = [-RoomHeight,RoomHeight];
                else
                    app.UIAxes.YTick = (0:0.1:RoomHeight);
                    app.UIAxes.YTickLabel = (0:0.1:RoomHeight);
                    app.UIAxes.YLim = [0,RoomHeight];
                end
        end

        % Button pushed function: PlotAnchorsButton
        function PlotAnchorsButtonPushed(app, event)
            numOfAnchors = app.EnternumberofanchorsEditField.Value;
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            for i=1:numOfAnchors
                PX = app.listOfXFiled(i).Value;
                PY = app.listOfYFiled(i).Value;
                if app.positioning3D == 1
                    PZ = app.listOfZFiled(i).Value;
                    plot3(app.UIAxes, PX, PY, PZ,'.g', 'MarkerSize', 20);
                    text(app.UIAxes, PX + app.dx, PY, PZ, app.listOfLetters(i), "FontSize", 15);
                else
                    plot(app.UIAxes, PX, PY, '.g', 'MarkerSize', 20);
                    text(app.UIAxes, PX + app.dx, PY, app.listOfLetters(i), "FontSize", 15);
                end
            end
             
        end

        % Button pushed function: StartPositioningButton
        function StartPositioningButtonPushed(app, event)
            app.positioningState = 1;
            app.dwm3000Device = serialport(app.port, 115200);
            configureTerminator(app.dwm3000Device,"CR");
            app.InformationsTextArea.Value = "Rozpoczęto pozycjonowanie";
            app.Dor2DPositioningButtonGroup.Enable = "Off";
            app.SerialportDropDown.Editable = "Off";
            app.SerialportDropDown.Enable = "Off";
            app.PlotAnchorsButton.Enable = "Off";
            app.ApplysettingsButton.Enable = "Off";
            app.BrowseButton.Enable = "Off";
            app.LoadsettingsButton.Enable = "Off";
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "On";
            while(app.positioningState)
                tic;
                Get_Position(app);
                timeArrival = round(toc*1000, 2);
                app.timeArrivalArray(end+1) = timeArrival;
                app.PositionacquisitiontimemsTextArea.Value = num2str(timeArrival);
                if (length(app.timeArrivalArray) >= 100)
                    app.MeanpositionacquisitiontimemsTextArea.Value = num2str(round(mean(app.timeArrivalArray),2));
                    app.timeArrivalArray = [];
                end
            end
        end

        % Button pushed function: StopPositioningButton
        function StopPositioningButtonPushed(app, event)
            app.positioningState = 0;
            app.dwm3000Device = [];
            app.InformationsTextArea.Value = "Zatrzymano pozycjonowanie";
            app.Dor2DPositioningButtonGroup.Enable = "On";
            app.SerialportDropDown.Editable = "On";
            app.SerialportDropDown.Enable = "On";
            app.PlotAnchorsButton.Enable = "On";
            app.ApplysettingsButton.Enable = "On";
            app.BrowseButton.Enable = "On";
            app.LoadsettingsButton.Enable = "On";
            app.StartPositioningButton.Enable = "On";
            app.StopPositioningButton.Enable = "Off";

            % Save logs to txt file
            if(app.LogpositionsanddistancesCheckBox.Value)
                %tag E
                app.txt_hPosition_E= fopen("position_E.txt", 'w');
                for i=1:length(app.txt_position_E)
                    fprintf(app.txt_hPosition_E, app.txt_position_E(i));
                    fprintf(app.txt_hPosition_E,'\n');
                end
                fclose(app.txt_hPosition_E);
                app.txt_hDist_E = fopen("distances_E.txt", 'w');
                for i=1:length(app.txt_distances_E)
                    fprintf(app.txt_hDist_E, app.txt_distances_E(i));
                    fprintf(app.txt_hDist_E, '\n');
                end
                fclose(app.txt_hDist_E);
                % tag F
                app.txt_hPosition_F= fopen("position_F.txt", 'w');
                for i=1:length(app.txt_position_F)
                    fprintf(app.txt_hPosition_F, app.txt_position_F(i));
                    fprintf(app.txt_hPosition_F,'\n');
                end
                fclose(app.txt_hPosition_F);
                app.txt_hDist_F = fopen("distances_F.txt", 'w');
                for i=1:length(app.txt_distances_F)
                    fprintf(app.txt_hDist_F, app.txt_distances_F(i));
                    fprintf(app.txt_hDist_F, '\n');
                end
                fclose(app.txt_hDist_F);
                % tag G
                app.txt_hPosition_G= fopen("position_G.txt", 'w');
                for i=1:length(app.txt_position_G)
                    fprintf(app.txt_hPosition_G, app.txt_position_G(i));
                    fprintf(app.txt_hPosition_G,'\n');
                end
                fclose(app.txt_hPosition_G);
                app.txt_hDist_G = fopen("distances_G.txt", 'w');
                for i=1:length(app.txt_distances_G)
                    fprintf(app.txt_hDist_G, app.txt_distances_G(i));
                    fprintf(app.txt_hDist_G, '\n');
                end
                fclose(app.txt_hDist_G);

                % clear strings arrays
                app.txt_position_E = strings();
                app.txt_position_F = strings();
                app.txt_position_G = strings();
                app.txt_distances_E = strings();
                app.txt_distances_F = strings();
                app.txt_distances_G = strings();
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: YEditField_3
        function YEditField_3ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: XEditField_3
        function XEditField_3ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: YEditField_4
        function YEditField_4ValueChanged(app, event)
           %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: XEditField_4
        function XEditField_4ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: XEditField_5
        function XEditField_5ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: YEditField_5
        function YEditField_5ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: YEditField_6
        function YEditField_6ValueChanged(app, event)
           %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Value changed function: XEditField_6
        function XEditField_6ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            
        end

        % Drop down opening function: SerialportDropDown
        function SerialportDropDownOpening(app, event)
            app.freeports = serialportlist("available");
            app.SerialportDropDown.Items = app.freeports;
        end

        % Value changed function: SerialportDropDown
        function SerialportDropDownValueChanged(app, event)
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
        end

        % Selection changed function: Dor2DPositioningButtonGroup
        function Dor2DPositioningButtonGroupSelectionChanged(app, event)
            selectedButton = app.Dor2DPositioningButtonGroup.SelectedObject;
            if (selectedButton == app.POS_3D)
               app.positioning3D = 1;
               view(app.UIAxes,[-5 -5 5]);
               app.StartPositioningButton.Enable = "Off";
               app.StopPositioningButton.Enable = "Off";
            else
               app.positioning3D = 0;
               view(app.UIAxes,[0 0 10]);
               app.StartPositioningButton.Enable = "Off";
               app.StopPositioningButton.Enable = "Off";
            end
        end

        % Value changed function: ZEditField_7
        function ZEditField_7ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
            RoomDepth = app.ZEditField_7.Value;
            app.ZEditField.Limits = [app.minCord,RoomDepth - app.minCord];
            app.ZEditField_2.Limits = [app.minCord,RoomDepth - app.minCord];
            app.ZEditField_3.Limits = [app.minCord,RoomDepth - app.minCord];
            app.ZEditField_4.Limits = [app.minCord,RoomDepth - app.minCord];
            app.ZEditField_5.Limits = [app.minCord,RoomDepth - app.minCord];
            app.ZEditField_6.Limits = [app.minCord,RoomDepth - app.minCord];
            
            if (app.MinusViewCheckBox.Value)
                app.UIAxes.ZTick = (-RoomDepth:0.1:RoomDepth);
                app.UIAxes.ZTickLabel = (-RoomDepth:0.1:RoomDepth);
                app.UIAxes.ZLim = [-RoomDepth,RoomDepth];
            else
                app.UIAxes.ZTick = (0:0.1:RoomDepth);
                app.UIAxes.ZTickLabel = (0:0.1:RoomDepth);
                app.UIAxes.ZLim = [0,RoomDepth];
            end
            
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
        end

        % Value changed function: ZEditField_2
        function ZEditField_2ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
        end

        % Value changed function: ZEditField_3
        function ZEditField_3ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
        end

        % Value changed function: ZEditField_4
        function ZEditField_4ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
        end

        % Value changed function: ZEditField_5
        function ZEditField_5ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
        end

        % Value changed function: ZEditField_6
        function ZEditField_6ValueChanged(app, event)
            %disable start and stop positionign buttons
            app.StartPositioningButton.Enable = "Off";
            app.StopPositioningButton.Enable = "Off";
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            try 
                 [file, path] = uigetfile('*.txt','Select settings of coordinates');
                 app.ChoosefilewithsettingsTextArea.Value = path;
                 app.appData = file;
            catch
                 app.InformationsTextArea.Value = "Nie wybrano pliku/problem z plikiem/zamknięto okno wyszukiwania pliku";
            end
        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            try
                dataFile = fopen(app.appData);
                firstLine = fgetl(dataFile);
                splitFirstLine = split(firstLine,":");
                flag = 1;
                numOfAnchors = str2num(convertCharsToStrings(splitFirstLine(2)));
                if (numOfAnchors > 6) || (numOfAnchors < 3)
                    app.InformationsTextArea.Value = "To much or not enough anchors!"
                else
                    app.EnternumberofanchorsEditField.Value = numOfAnchors;
                    % Enable number of fields
                    enableXYFields(app,1, numOfAnchors);
                    % Disable rest of fields
                    if (numOfAnchors < app.maxNumAnchors)
                        disableXYFields(app, numOfAnchors+1, app.maxNumAnchors);
                    end
                    %disable start and stop positionign buttons
                    app.StartPositioningButton.Enable = "Off";
                    app.StopPositioningButton.Enable = "Off";
                end
                
                i = 1;
                while ~feof(dataFile)
                    line(i) = convertCharsToStrings(fgetl(dataFile));
                    if (line(i) == "-")
                        if ( (i - 1) ~= numOfAnchors)
                            app.InformationsTextArea.Value = "To much or not enough coords for anchors!";
                            flag = 0;
                            break;
                        end
                    end
                    i = i + 1;
                end
                if (flag == 1)
                    %fill size room
                    splitData = str2double(split(line(end),":"));
                    app.XEditField_7.Value = splitData(1);
                    app.YEditField_7.Value = splitData(2);
                    app.ZEditField_7.Value = splitData(3);
                    % make event value change
                    app.XEditField_7ValueChanged;
                    app.YEditField_7ValueChanged;
                    app.ZEditField_7ValueChanged;
                    % fill coords of anchors
                    for i=1:numOfAnchors
                        splitData = str2double(split(line(i),":"));
                        app.listOfXFiled(i).Value = splitData(1);
                        app.listOfYFiled(i).Value = splitData(2);
                        app.listOfZFiled(i).Value = splitData(3);
                    end
                end
                
                fclose(dataFile);
                app.InformationsTextArea.Value = "Załadowano dane";
            catch
                app.InformationsTextArea.Value = "Problem z załadowaniem danych z pliku/nie wybrano pliku";
            end
        end

        % Button pushed function: SavesettingsastxtButton
        function SavesettingsastxtButtonPushed(app, event)
            try
                [file, path] = uiputfile('myappData.txt');
                dataFile = fopen(file, 'w');
                numOfAnchors = app.EnternumberofanchorsEditField.Value;
                NumA = "NumA:" + num2str(numOfAnchors) + "\n";
                fprintf(dataFile,NumA);
                for i=1:numOfAnchors
                    X = sprintf("%.2f",app.listOfXFiled(i).Value);
                    Y = sprintf("%.2f",app.listOfYFiled(i).Value);
                    Z = sprintf("%.2f",app.listOfZFiled(i).Value);
                    lines = X + ":" + Y + ":" + Z + "\n";
                    fprintf(dataFile,lines);
                end
                cutLine = "-\n";
                fprintf(dataFile, cutLine);
                X = sprintf("%.2f",app.XEditField_7.Value);
                Y = sprintf("%.2f",app.YEditField_7.Value);
                Z = sprintf("%.2f",app.ZEditField_7.Value);
                roomLine = X + ":" + Y + ":" + Z + "\n";
                fprintf(dataFile, roomLine);
                fclose(dataFile);
            catch
                app.InformationsTextArea.Value = "Problem z zapisaniem danych do pliku/zamknięto okno zapisu";
            end
        end

        % Value changed function: LogpositionsanddistancesCheckBox
        function LogpositionsanddistancesCheckBoxValueChanged(app, event)
            value = app.LogpositionsanddistancesCheckBox.Value;
            
        end

        % Value changed function: MinusViewCheckBox
        function MinusViewCheckBoxValueChanged(app, event)
            value = app.MinusViewCheckBox.Value;
            RoomDepth = app.ZEditField_7.Value;
            RoomWidth = app.XEditField_7.Value;
            RoomHeight = app.YEditField_7.Value;
            if (value)
                    app.UIAxes.XTick = (-RoomWidth:0.1:RoomWidth);
                    app.UIAxes.XTickLabel = (-RoomWidth:0.1:RoomWidth);
                    app.UIAxes.XLim = [-RoomWidth,RoomWidth];
            else
                    app.UIAxes.XTick = (0:0.1:RoomWidth);
                    app.UIAxes.XTickLabel = (0:0.1:RoomWidth);
                    app.UIAxes.XLim = [0,RoomWidth];
            end
            if (value)
                app.UIAxes.YTick = (-RoomHeight:0.1:RoomHeight);
                app.UIAxes.YTickLabel = (-RoomHeight:0.1:RoomHeight);
                app.UIAxes.YLim = [-RoomHeight,RoomHeight];
            else
                app.UIAxes.YTick = (0:0.1:RoomHeight);
                app.UIAxes.YTickLabel = (0:0.1:RoomHeight);
                app.UIAxes.YLim = [0,RoomHeight];
            end
            if (value)
                app.UIAxes.ZTick = (-RoomDepth:0.1:RoomDepth);
                app.UIAxes.ZTickLabel = (-RoomDepth:0.1:RoomDepth);
                app.UIAxes.ZLim = [-RoomDepth,RoomDepth];
            else
                app.UIAxes.ZTick = (0:0.1:RoomDepth);
                app.UIAxes.ZTickLabel = (0:0.1:RoomDepth);
                app.UIAxes.ZLim = [0,RoomDepth];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Positioning')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes.ZTickLabel = '';
            app.ApplysettingsButton = uibutton(app.UIFigure, 'push');
            app.ApplysettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplysettingsButtonPushed, true);
            app.ApplysettingsButton.Text = 'Apply settings';
            app.EnternumberofanchorsEditFieldLabel = uilabel(app.UIFigure);
            app.EnternumberofanchorsEditFieldLabel.Text = 'Enter number of anchors';
            app.EnternumberofanchorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnternumberofanchorsEditField.ValueChangedFcn = createCallbackFcn(app, @EnternumberofanchorsEditFieldValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.EnterpositionsofanchorsLabel = uilabel(app.UIFigure);
            app.EnterpositionsofanchorsLabel.Text = 'Enter positions of anchors';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A.';
            app.RoomsizeLabel = uilabel(app.UIFigure);
            app.RoomsizeLabel.Text = 'Room size';
            app.InformationsTextAreaLabel = uilabel(app.UIFigure);
            app.InformationsTextAreaLabel.Text = 'Informations';
            app.InformationsTextArea = uitextarea(app.UIFigure);
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B.';
            app.XEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_3.ValueChangedFcn = createCallbackFcn(app, @XEditField_3ValueChanged, true);
            app.YEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_3.ValueChangedFcn = createCallbackFcn(app, @YEditField_3ValueChanged, true);
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = 'C.';
            app.XEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_4.ValueChangedFcn = createCallbackFcn(app, @XEditField_4ValueChanged, true);
            app.YEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_4.ValueChangedFcn = createCallbackFcn(app, @YEditField_4ValueChanged, true);
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = 'D.';
            app.XEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_5.ValueChangedFcn = createCallbackFcn(app, @XEditField_5ValueChanged, true);
            app.YEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_5.ValueChangedFcn = createCallbackFcn(app, @YEditField_5ValueChanged, true);
            app.ELabel_2 = uilabel(app.UIFigure);
            app.ELabel_2.Text = 'E.';
            app.XEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_6.ValueChangedFcn = createCallbackFcn(app, @XEditField_6ValueChanged, true);
            app.YEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_6.ValueChangedFcn = createCallbackFcn(app, @YEditField_6ValueChanged, true);
            app.FLabel_2 = uilabel(app.UIFigure);
            app.FLabel_2.Text = 'F.';
            app.XEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_7.ValueChangedFcn = createCallbackFcn(app, @XEditField_7ValueChanged, true);
            app.YEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_7.ValueChangedFcn = createCallbackFcn(app, @YEditField_7ValueChanged, true);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '7.';
            app.MinMax36Label = uilabel(app.UIFigure);
            app.MinMax36Label.Text = '(Min-Max: 3-6)';
            app.Max10x10x10mLabel = uilabel(app.UIFigure);
            app.Max10x10x10mLabel.Text = '(Max: 10x10x10 [m])';
            app.PlotAnchorsButton = uibutton(app.UIFigure, 'push');
            app.PlotAnchorsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAnchorsButtonPushed, true);
            app.PlotAnchorsButton.Text = 'Plot Anchors';
            app.XEditFieldLabel_2 = uilabel(app.UIFigure);
            app.XEditFieldLabel_2.Text = 'X';
            app.YEditFieldLabel_2 = uilabel(app.UIFigure);
            app.YEditFieldLabel_2.Text = 'Y';
            app.D2DPositioningSystemLabel = uilabel(app.UIFigure);
            app.D2DPositioningSystemLabel.Text = '3D & 2D Positioning System';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '___________________________________________________________________________________________________';
            app.StartPositioningButton = uibutton(app.UIFigure, 'push');
            app.StartPositioningButton.ButtonPushedFcn = createCallbackFcn(app, @StartPositioningButtonPushed, true);
            app.StartPositioningButton.Tooltip = {'Push apply settings to start positioning!'};
            app.StartPositioningButton.Text = 'Start Positioning';
            app.StopPositioningButton = uibutton(app.UIFigure, 'push');
            app.StopPositioningButton.ButtonPushedFcn = createCallbackFcn(app, @StopPositioningButtonPushed, true);
            app.StopPositioningButton.Tooltip = {'Push apply settings to start positioning!'};
            app.StopPositioningButton.Text = 'Stop Positioning';
            app.SerialportDropDownLabel = uilabel(app.UIFigure);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.UIFigure);
            app.SerialportDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialportDropDownOpening, true);
            app.SerialportDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialportDropDownValueChanged, true);
            app.ChooseserialportLabel = uilabel(app.UIFigure);
            app.ChooseserialportLabel.Text = 'Choose serial port';
            app.X_PosTextAreaLabel = uilabel(app.UIFigure);
            app.X_PosTextAreaLabel.Text = 'X_Pos';
            app.X_PosTextArea = uitextarea(app.UIFigure);
            app.Y_PosTextAreaLabel = uilabel(app.UIFigure);
            app.Y_PosTextAreaLabel.Text = 'Y_Pos';
            app.Y_PosTextArea = uitextarea(app.UIFigure);
            app.CurrentPositionLabel = uilabel(app.UIFigure);
            app.CurrentPositionLabel.Text = 'Current Position';
            app.Dor2DPositioningButtonGroup = uibuttongroup(app.UIFigure);
            app.Dor2DPositioningButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Dor2DPositioningButtonGroupSelectionChanged, true);
            app.Dor2DPositioningButtonGroup.Title = '3D or 2D Positioning';
            app.POS_2D = uiradiobutton(app.Dor2DPositioningButtonGroup);
            app.POS_2D.Text = '2D';
            app.POS_2D.Value = true;
            app.POS_3D = uiradiobutton(app.Dor2DPositioningButtonGroup);
            app.POS_3D.Text = '3D';
            app.Choose3Dor2DpositioningLabel = uilabel(app.UIFigure);
            app.Choose3Dor2DpositioningLabel.Text = 'Choose 3D or 2D positioning';
            app.Z_PosTextAreaLabel = uilabel(app.UIFigure);
            app.Z_PosTextAreaLabel.Text = 'Z_Pos';
            app.Z_PosTextArea = uitextarea(app.UIFigure);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZEditField_2ValueChanged, true);
            app.ZEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_3.ValueChangedFcn = createCallbackFcn(app, @ZEditField_3ValueChanged, true);
            app.ZEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_4.ValueChangedFcn = createCallbackFcn(app, @ZEditField_4ValueChanged, true);
            app.ZEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_5.ValueChangedFcn = createCallbackFcn(app, @ZEditField_5ValueChanged, true);
            app.ZEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_6.ValueChangedFcn = createCallbackFcn(app, @ZEditField_6ValueChanged, true);
            app.ZEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_7.ValueChangedFcn = createCallbackFcn(app, @ZEditField_7ValueChanged, true);
            app.ZEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChoosefilewithsettingsTextAreaLabel = uilabel(app.UIFigure);
            app.ChoosefilewithsettingsTextAreaLabel.Text = 'Choose file with settings';
            app.ChoosefilewithsettingsTextArea = uitextarea(app.UIFigure);
            app.ChoosefilewithsettingsTextArea.Tooltip = {'Choose file with saved positions of anchors and other settings'};
            app.LoadsettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.SavesettingsastxtButton = uibutton(app.UIFigure, 'push');
            app.SavesettingsastxtButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsastxtButtonPushed, true);
            app.SavesettingsastxtButton.Text = 'Save settings as txt';
            app.PositionacquisitiontimemsTextAreaLabel = uilabel(app.UIFigure);
            app.PositionacquisitiontimemsTextAreaLabel.Text = 'Position acquisition time [ms]';
            app.PositionacquisitiontimemsTextArea = uitextarea(app.UIFigure);
            app.MeanpositionacquisitiontimemsTextAreaLabel = uilabel(app.UIFigure);
            app.MeanpositionacquisitiontimemsTextAreaLabel.Text = 'Mean position acquisition time [ms]';
            app.MeanpositionacquisitiontimemsTextArea = uitextarea(app.UIFigure);
            app.MeanpositionacquisitiontimemsTextArea.Tooltip = {'Mean time based on 100 samples of time arrival'};
            app.X_PosTextArea_2Label = uilabel(app.UIFigure);
            app.X_PosTextArea_2Label.Text = 'X_Pos';
            app.X_PosTextArea_2 = uitextarea(app.UIFigure);
            app.Y_PosTextArea_2Label = uilabel(app.UIFigure);
            app.Y_PosTextArea_2Label.Text = 'Y_Pos';
            app.Y_PosTextArea_2 = uitextarea(app.UIFigure);
            app.Z_PosTextArea_2Label = uilabel(app.UIFigure);
            app.Z_PosTextArea_2Label.Text = 'Z_Pos';
            app.Z_PosTextArea_2 = uitextarea(app.UIFigure);
            app.X_PosTextArea_3Label = uilabel(app.UIFigure);
            app.X_PosTextArea_3Label.Text = 'X_Pos';
            app.X_PosTextArea_3 = uitextarea(app.UIFigure);
            app.Y_PosTextArea_3Label = uilabel(app.UIFigure);
            app.Y_PosTextArea_3Label.Text = 'Y_Pos';
            app.Y_PosTextArea_3 = uitextarea(app.UIFigure);
            app.Z_PosTextArea_3Label = uilabel(app.UIFigure);
            app.Z_PosTextArea_3Label.Text = 'Z_Pos';
            app.Z_PosTextArea_3 = uitextarea(app.UIFigure);
            app.ELabel = uilabel(app.UIFigure);
            app.ELabel.Text = 'E:';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F:';
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = 'G:';
            app.LogpositionsanddistancesCheckBox = uicheckbox(app.UIFigure);
            app.LogpositionsanddistancesCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogpositionsanddistancesCheckBoxValueChanged, true);
            app.LogpositionsanddistancesCheckBox.Tooltip = {'This option saves positions and distances of each tag (initiator) with time in txt files'};
            app.LogpositionsanddistancesCheckBox.Text = 'Log positions and distances';
            app.MinusViewCheckBox = uicheckbox(app.UIFigure);
            app.MinusViewCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinusViewCheckBoxValueChanged, true);
            app.MinusViewCheckBox.Text = 'Minus View';
            app.RSSITextAreaLabel = uilabel(app.UIFigure);
            app.RSSITextAreaLabel.Text = 'RSSI';
            app.RSSITextArea_A = uitextarea(app.UIFigure);
            app.RSSITextArea_B = uitextarea(app.UIFigure);
            app.RSSITextArea_C = uitextarea(app.UIFigure);
            app.RSSITextArea_D = uitextarea(app.UIFigure);
            app.RSSITextArea_E = uitextarea(app.UIFigure);
            app.RSSITextArea_F = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Positioning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end